classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Detect_Vehicles  matlab.ui.control.Button
        Load_Image       matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        YOLODetector 
        InputImage  
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            data = load('trainedVehicleDetector.mat');
            app.YOLODetector = data.detector;
        end

        % Button pushed function: Load_Image
        function Load_ImageButtonPushed2(app, event)
            [filename, pathname] = uigetfile({'*.jpg;*.png;*.jpeg', 'Image Files'});
            if ischar(filename)
                app.InputImage = imread(fullfile(pathname, filename));
                imshow(app.InputImage, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: Detect_Vehicles
        function Detect_VehiclesButtonPushed2(app, event)
            if isempty(app.InputImage)
                uialert(app.UIFigure, 'Please load an image first.', 'Error');
                return;
            end

            app.UIFigure.Pointer = 'watch';
            drawnow;

            try
                [bboxes, scores, labels] = detect(app.YOLODetector, app.InputImage, 'Threshold', 0.15);

                if ~isempty(bboxes)
                    labelStrs = string(labels) + ": " + string(round(scores, 2));
            
                    annotatedImage = insertObjectAnnotation(app.InputImage, 'rectangle', bboxes, labelStrs);
            
                    imshow(annotatedImage, 'Parent', app.UIAxes);
                else
                    uialert(app.UIFigure, 'No vehicles detected in this image.', 'Result');
                end
            catch ME
                uialert(app.UIFigure, ME.message, 'Error Occurred');
            end

            app.UIFigure.Pointer = 'arrow';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.Load_Image = uibutton(app.UIFigure, 'push');
            app.Load_Image.ButtonPushedFcn = createCallbackFcn(app, @Load_ImageButtonPushed2, true);
            app.Load_Image.Text = 'Load Image';
            app.Detect_Vehicles = uibutton(app.UIFigure, 'push');
            app.Detect_Vehicles.ButtonPushedFcn = createCallbackFcn(app, @Detect_VehiclesButtonPushed2, true);
            app.Detect_Vehicles.Text = 'Detect Vehicles';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end